classdef HSV_Slider < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EditField                   matlab.ui.control.NumericEditField
        EditField_2                 matlab.ui.control.NumericEditField
        EditField_3                 matlab.ui.control.NumericEditField
        EditField_4                 matlab.ui.control.NumericEditField
        EditField_5                 matlab.ui.control.NumericEditField
        EditField_6                 matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        Hue_highSliderLabel         matlab.ui.control.Label
        Hue_highSlider              matlab.ui.control.Slider
        Hue_lowSliderLabel          matlab.ui.control.Label
        Hue_lowSlider               matlab.ui.control.Slider
        Saturation_highSliderLabel  matlab.ui.control.Label
        Saturation_highSlider       matlab.ui.control.Slider
        Saturation_lowSliderLabel   matlab.ui.control.Label
        Saturation_lowSlider        matlab.ui.control.Slider
        Value_highSliderLabel       matlab.ui.control.Label
        Value_highSlider            matlab.ui.control.Slider
        Value_lowSliderLabel        matlab.ui.control.Label
        Value_lowSlider             matlab.ui.control.Slider
        HSVSliderLabel              matlab.ui.control.Label
        Label                       matlab.ui.control.Label
    end

    
    properties (Access = private)
        hueThresholdHigh=0
        hueThresholdLow=0
        saturationThresholdHigh=0
        saturationThresholdLow=0
        valueThresholdHigh=0
        valueThresholdLow=0
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cam=webcam(1);
            while(1)
                [rgbImage, ~] = snapshot(cam);
                
                %converting rgb to hsv
                hsvImage = rgb2hsv(rgbImage);
                %imshow(hsvImage,'Parent',app.UIAxes2);
                
                hImage = hsvImage(:,:,1);
                sImage = hsvImage(:,:,2);
                vImage = hsvImage(:,:,3);
                
                hueMask = (hImage>= app.hueThresholdLow) & (hImage<= app.hueThresholdHigh);
                saturationMask = (sImage >= app.saturationThresholdLow) & (sImage <= app.saturationThresholdHigh);
                valueMask = (vImage >= app.valueThresholdLow) & (vImage <= app.valueThresholdHigh);
                coloredObjectsMask = uint8(hueMask & saturationMask & valueMask);
                
                imshow(rgbImage,'Parent',app.UIAxes);
                imshow(coloredObjectsMask,[],'Parent',app.UIAxes2);
            end
            
        end

        % Value changed function: Hue_highSlider
        function Hue_highSliderValueChanged(app, event)
    Hue_high_val = app.Hue_highSlider.Value;
    app.EditField.Value=Hue_high_val;
    app.hueThresholdHigh=Hue_high_val;
        end

        % Value changed function: Hue_lowSlider
        function Hue_lowSliderValueChanged(app, event)
    Hue_low_val = app.Hue_lowSlider.Value;
    app.EditField_2.Value=Hue_low_val;
    app.hueThresholdLow=Hue_low_val;
        end

        % Value changed function: Saturation_highSlider
        function Saturation_highSliderValueChanged(app, event)
    Sat_high_val = app.Saturation_highSlider.Value;
    app.EditField_3.Value=Sat_high_val;
    app.saturationThresholdHigh=Sat_high_val;
    
        end

        % Value changed function: Saturation_lowSlider
        function Saturation_lowSliderValueChanged(app, event)
    Sat_low_val = app.Saturation_lowSlider.Value;
    app.EditField_4.Value=Sat_low_val;
    app.saturationThresholdLow=Sat_low_val;
        end

        % Value changed function: Value_highSlider
        function Value_highSliderValueChanged(app, event)
    Val_high_val = app.Value_highSlider.Value;
    app.EditField_5.Value=Val_high_val;
    app.valueThresholdHigh=Val_high_val;
        end

        % Value changed function: Value_lowSlider
        function Value_lowSliderValueChanged(app, event)
    Val_low_val = app.Value_lowSlider.Value;
    app.EditField_6.Value=Val_low_val;
    app.valueThresholdLow=Val_low_val;
        end

        % Callback function
        function StartCameraButtonPushed(app, event)
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_6 = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Hue_highSliderLabel = uilabel(app.UIFigure);
            app.Hue_highSliderLabel.Text = 'Hue_high';
            app.Hue_highSlider = uislider(app.UIFigure);
            app.Hue_highSlider.Limits = [0 255];
            app.Hue_highSlider.ValueChangedFcn = createCallbackFcn(app, @Hue_highSliderValueChanged, true);
            app.Hue_lowSliderLabel = uilabel(app.UIFigure);
            app.Hue_lowSliderLabel.Text = 'Hue_low';
            app.Hue_lowSlider = uislider(app.UIFigure);
            app.Hue_lowSlider.Limits = [0 255];
            app.Hue_lowSlider.ValueChangedFcn = createCallbackFcn(app, @Hue_lowSliderValueChanged, true);
            app.Saturation_highSliderLabel = uilabel(app.UIFigure);
            app.Saturation_highSliderLabel.Text = 'Saturation_high';
            app.Saturation_highSlider = uislider(app.UIFigure);
            app.Saturation_highSlider.Limits = [0 255];
            app.Saturation_highSlider.ValueChangedFcn = createCallbackFcn(app, @Saturation_highSliderValueChanged, true);
            app.Saturation_lowSliderLabel = uilabel(app.UIFigure);
            app.Saturation_lowSliderLabel.Text = 'Saturation_low';
            app.Saturation_lowSlider = uislider(app.UIFigure);
            app.Saturation_lowSlider.Limits = [0 255];
            app.Saturation_lowSlider.ValueChangedFcn = createCallbackFcn(app, @Saturation_lowSliderValueChanged, true);
            app.Value_highSliderLabel = uilabel(app.UIFigure);
            app.Value_highSliderLabel.Text = 'Value_high';
            app.Value_highSlider = uislider(app.UIFigure);
            app.Value_highSlider.Limits = [0 255];
            app.Value_highSlider.ValueChangedFcn = createCallbackFcn(app, @Value_highSliderValueChanged, true);
            app.Value_lowSliderLabel = uilabel(app.UIFigure);
            app.Value_lowSliderLabel.Text = 'Value_low';
            app.Value_lowSlider = uislider(app.UIFigure);
            app.Value_lowSlider.Limits = [0 255];
            app.Value_lowSlider.ValueChangedFcn = createCallbackFcn(app, @Value_lowSliderValueChanged, true);
            app.HSVSliderLabel = uilabel(app.UIFigure);
            app.HSVSliderLabel.Text = 'HSV Slider';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = ' 2021.1.0.0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HSV_Slider

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end